classdef main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                          matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        LoadSettingsFileMenu          matlab.ui.container.Menu
        SaveSettingsFileMenu          matlab.ui.container.Menu
        BatchMenu                     matlab.ui.container.Menu
        ViewMenu                      matlab.ui.container.Menu
        OpenfiguresinnewwindowMenu    matlab.ui.container.Menu
        ExportMenu                    matlab.ui.container.Menu
        IBIFileMenu                   matlab.ui.container.Menu
        MatFileMenu                   matlab.ui.container.Menu
        OpeninHRVASMenu               matlab.ui.container.Menu
        CSVResultsMenu                matlab.ui.container.Menu
        HelpMenu                      matlab.ui.container.Menu
        AboutMenu                     matlab.ui.container.Menu
        DocumentationMenu             matlab.ui.container.Menu
        uipanel3                      matlab.ui.container.Panel
        axes1                         matlab.ui.control.UIAxes
        axes2                         matlab.ui.control.UIAxes
        axes3                         matlab.ui.control.UIAxes
        SearchSliderLabel             matlab.ui.control.Label
        SearchSlider                  matlab.ui.control.Slider
        ZoomSpinnerLabel              matlab.ui.control.Label
        ZoomSpinner                   matlab.ui.control.Spinner
        uipanel2                      matlab.ui.container.Panel
        runButton                     matlab.ui.control.Button
        results                       matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        ProgramStateLampLabel         matlab.ui.control.Label
        ProgramStateLamp              matlab.ui.control.Lamp
        TachogramGenerationPanel      matlab.ui.container.Panel
        Switch                        matlab.ui.control.Switch
        medianFilterPostCheckBox      matlab.ui.control.CheckBox
        CoefLabel                     matlab.ui.control.Label
        smoothingSplinesCoefEdit      matlab.ui.control.NumericEditField
        WindowSizeLabel               matlab.ui.control.Label
        windowSizePostEdit            matlab.ui.control.NumericEditField
        splinesRSquareText            matlab.ui.control.Label
        text24                        matlab.ui.control.Label
        BeatProcessingPanel           matlab.ui.container.Panel
        ensembleCheckBox              matlab.ui.control.CheckBox
        mediaCheckBox                 matlab.ui.control.CheckBox
        missedBeatsCheckBox           matlab.ui.control.CheckBox
        MinimumCorrelationLabel       matlab.ui.control.Label
        minimumCorrelationEdit        matlab.ui.control.NumericEditField
        windowSizeEdit                matlab.ui.control.NumericEditField
        TauLabel                      matlab.ui.control.Label
        missedBeatsTolerancePercentEdit  matlab.ui.control.NumericEditField
        ToleranceLabel                matlab.ui.control.Label
        numMissedBeatsText            matlab.ui.control.Label
        numRemovedBeatsMadFilterText  matlab.ui.control.Label
        numRemovedBeatsEnsembleText   matlab.ui.control.Label
        text16                        matlab.ui.control.Label
        FileSelectionPanel            matlab.ui.container.Panel
        fileSelectButton              matlab.ui.control.Button
        text34                        matlab.ui.control.Label
        filename                      matlab.ui.control.Label
        ParallelPoolButton            matlab.ui.control.StateButton
    end


    properties (Access = private)
        fileName % Filename of the ECG file
        pathName % Filepath of the ECG file
    end

    properties (Access = public)
        result % Description
    end
    
    methods (Access = private)
        function evaluate(app)
            
            switch app.Switch.Value
                case 'Smoothing Splines'
                      interpolationMethod = 'spline';
                case 'Direct'
                      interpolationMethod = 'linear';
            end
            
            options = {};
            
            if(app.ensembleCheckBox.Value)
                options = [options, 'ensemble_filter_threshold', app.minimumCorrelationEdit.Value];
            end
            if(app.mediaCheckBox.Value)
                options = [options, 'mad_filter_threshold', app.windowSizeEdit.Value];
            end
            if(app.missedBeatsCheckBox.Value)
                options = [options, 'missed_beats_tolerance_percent', app.missedBeatsTolerancePercentEdit.Value];
            end
            if(app.medianFilterPostCheckBox.Value)
                options = [options, 'median_filter_window', app.windowSizePostEdit.Value];
            end
            if(~isempty(app.pathName))
                options = [options, 'directory', app.pathName]; % used if not in matlab path
            end
            
            options = [options, 'interpolation_method', interpolationMethod];
            options = [options, 'smoothing_spline_coef', app.smoothingSplinesCoefEdit.Value]; % used if interpolation method is spline
            options = [options, 'eval_type', 'full']; % required for plotting
            
            app.result = hrvDetect(app.fileName, options);
            
        end
        
        
        function makePlots(app, plotInSeparateFigure)
            cla(app.axes1);
            cla(app.axes2);
            cla(app.axes3);
            
            
            if(isempty(app.result))
                % Not run yet
                time = [];
            else
                errorFlags = app.result.evaluation.errorFlags;
            
                ecgErrors = errorFlags.invalid_r_peaks_ensemble; % Logical to allow indexing
                noisyIntervals = errorFlags.invalid_rr_intervals_all;
                noisyIntsEnsemble = errorFlags.invalid_rr_intervals_ensemble;
                noisyIntsMissedBeats = errorFlags.invalid_rr_intervals_missed;
                noisyIntsMadFilter = errorFlags.invalid_rr_intervals_madFiltered;
                all_R_locs = errorFlags.all_R_locs;
            
                time = app.result.evaluation.time;
                heartRate = app.result.heartRate;
                roughTacho = app.result.evaluation.roughTacho;
                R_locs = app.result.R_locs;
                detrended = app.result.evaluation.detrended;
                fs = app.result.fs;
            end
            
            if(~isempty(time))
                plot(app.axes1,time,detrended);
                hold(app.axes1, 'on');
                scatter(app.axes1,time(all_R_locs(~ecgErrors)),detrended(all_R_locs(~ecgErrors)),'bv','MarkerFaceColor','b')
                if(sum(ecgErrors) ~= 0)
                    scatter(app.axes1,time(all_R_locs(ecgErrors)),detrended(all_R_locs(ecgErrors)),'rv','MarkerFaceColor','r')
                    legend(app.axes1,'ECG','R-Peaks', 'Uncorrelated Beats');
                else
                    legend(app.axes1,'ECG','R-Peaks');
                end
            end
            app.axes1.YLimMode = 'auto'; % auto scale Y axis


            if(~isempty(time))
                interval = diff(all_R_locs);
                BPM = 60*fs./(interval);
                plot(app.axes2, time(R_locs),roughTacho, 'DisplayName', 'Un-Smoothed Tachogram');
                hold(app.axes2, 'on');
                scatter(app.axes2,time(all_R_locs(noisyIntsEnsemble)),BPM(noisyIntsEnsemble),'DisplayName','Ensemble Filtered'); % TODO: Need to update BPM array
                scatter(app.axes2,time(all_R_locs(noisyIntsMissedBeats)),BPM(noisyIntsMissedBeats),'DisplayName','Missed Beats'); % TODO: Need to update BPM array
                scatter(app.axes2,time(all_R_locs(noisyIntsMadFilter)),BPM(noisyIntsMadFilter),'DisplayName','MAD Filtered'); % TODO: Need to update BPM array
                legend(app.axes2,'show');
            end
            app.axes2.YLim = [100 200];
            %ylim(app.axes2,[100 200]);
            
             
            if(~isempty(time))
                plot(app.axes3,time,heartRate, 'DisplayName', 'Final Tachogram');
                hold(app.axes3, 'on');
                scatter(app.axes3,time(all_R_locs(noisyIntervals)),heartRate(all_R_locs(noisyIntervals)),'DisplayName','Interpolated Data', ...
                    'MarkerEdgeColor','r',...
                    'MarkerFaceColor','r',...
                    'LineWidth',0.1);
                legend(app.axes3,'show');
            end
            app.axes3.YLim = [100 200];
            %ylim(app.axes3,[100 200]);
            
            % Enable Zooming and panning:
            app.ZoomSpinner.Enable = 'on';
            
            % Create axes1
            title(app.axes1, 'ECG Signal with R points')
            xlabel(app.axes1, 'Time (s)')
            ylabel(app.axes1, 'Amplitude')

            % Create axes2
            title(app.axes2, 'Tachogram')
            xlabel(app.axes2, 'Time (s)')
            ylabel(app.axes2, 'BPM')

            % Create axes3
            title(app.axes3, 'Filtered Tachogram')
            xlabel(app.axes3, 'Time (s)')
            ylabel(app.axes3, 'BPM')
        end
        
        function showEvaluationResults(app)
            if(isempty(app.results))
                app.numRemovedBeatsEnsembleText.Text = 0;
                app.numRemovedBeatsMadFilterText.Text = 0;
                app.numMissedBeatsText.Text = 0;
                app.splinesRSquareText.Text =  0;
            else
                eval = app.result.evaluation;
                app.numRemovedBeatsEnsembleText.Text = num2str(eval.numRemovedEnsemble);
                app.numRemovedBeatsMadFilterText.Text = num2str(eval.numRemovedMAD);
                app.numMissedBeatsText.Text = num2str(eval.missedBeatsNum);
                app.splinesRSquareText.Text =  num2str(eval.splineRSquare);
                d = {'Total Signal Length',length(eval.sig),'Samples';...
                     'Total Number of beats',eval.totalNumBeats,'Beats';...
                     'Sampling Frequency',app.result.fs,'Hertz';...
                     'Ensemble Filter',eval.numRemovedEnsemble,'beats removed';...
                     'MAD Filter',eval.numRemovedMAD,'beats removed';...
                     'Missed Beats',eval.missedBeatsNum,'beats removed';...
                     'Total Invalid',(eval.numRemovedEnsemble+eval.numRemovedMAD+eval.missedBeatsNum),'beats';...
                     'Total Invalid',eval.percentInvalid,'percent'...
                     };
                app.UITable.Data = d;
            end
        end 
    
        function updatePlotZooms(app, zoomValue, scrollValue)
            % Check if we have the data to plot:
            if(isempty(app.result))
                return
            elseif(isempty(app.result.evaluation))
                return
            end
            time = app.result.evaluation.time;
            XLim = computeMinMaxIndexes(app, time, zoomValue, scrollValue); 
            app.axes1.XLim = XLim;
            app.axes2.XLim = XLim;
            app.axes3.XLim = XLim;
        end
    
    
        % Compute the correct indices for the arrays
        function XLim = computeMinMaxIndexes(app, time, zoomValue, scrollValue) 
            
            % Calculate all the values in samples:
            N = length(time);
            numSamplesShown = N / zoomValue;
            
            % Find coordinates of display box
            center = round(scrollValue / 100 * N);
            left = round(center - numSamplesShown / 2);
            right = round(center + numSamplesShown / 2);
            
            % Adjust if necessary:
            if(left < 1)
                right = right + abs(left);
                left = 1;
            end  
            if(right > N)
                margin = right - N;
                right = N;
                left = max(left - margin, 1);
            end
            
            % Convert back to XLim:
            XLim = [time(left), time(right)];
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function main_OpeningFcn(app)
%             % This function has no output args, see OutputFcn.
%             % hObject    handle to figure
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             % varargin   command line arguments to main (see VARARGIN)
%             
%             % Choose default command line output for main
%             app.output = hObject;
%             
%             % Create a cluster
            if isempty(gcp)
                app.ParallelPoolButton.Value = 0; % off
            else
                app.ParallelPoolButton.Value = 1;
            end
%             
%             % Update handles structure
%             guidata(hObject, handles);
%             
%             init(hObject);
%             makePlots(hObject, false);
%             
%             % This sets up the initial plot - only do when we are invisible
%             % so window can get raised using main.
%             if strcmp(get(hObject,'Visible'),'off')
%                 %plot(rand(5));
%             end
        end

        % Button pushed function: fileSelectButton
        function fileSelectButton_Callback(app, event)
            % hObject    handle to fileSelectButton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            [app.fileName, app.pathName] = uigetfile('*.mat','Select the APEX file');
            if(length(app.fileName) < 2)
                message = sprintf('Please select a file');
                uialert(app.main,message,'Error','Icon','error');
                return;
            end
            app.fileName = app.fileName(1:end-4);
            app.filename.Text =  app.fileName;
        end

        % Button pushed function: runButton
        function runButton_Callback(app, event)

            app.ProgramStateLamp.Color = 'yellow';
            % Update plot:
            app.ZoomSpinner.Value = 1;
            app.SearchSlider.Value = 0;
            drawnow
            try
                evaluate(app);
                showEvaluationResults(app);
                makePlots(app);
                app.ProgramStateLamp.Color = 'green';
            catch ME
                app.ProgramStateLamp.Color = 'red';
                message = sprintf(strcat('Runtime Error: ',ME.message));
                uialert(app.main,message,'Error','Icon','error');
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('HRV Toolkit \n Version: 0.2 - Alpha \n See hrvToolkit.com for more information.');
            uialert(app.main,message,'About','Icon','info');
        end

        % Menu selected function: DocumentationMenu
        function DocumentationMenuSelected(app, event)
            url = 'http://hrvtoolkit.com';
            web(url,'-browser');
        end

        % Menu selected function: MatFileMenu
        function MatFileMenuSelected(app, event)
            if(isempty(app.result))
                message = sprintf('Please run the algorithm first');
                uialert(app.main,message,'Error','Icon','error');
                return;
            end
            res = app.result;
            filter = strcat(app.fileName(1:end-4),'.mat');
            [file,path] = uiputfile(filter);
            if(~isempty(file))
                save(fullfile(path,file),res);
            end
        end

        % Menu selected function: IBIFileMenu
        function IBIFileMenuSelected(app, event)
            if(isempty(app.result))
                message = sprintf('Please run the algorithm first');
                uialert(app.main,message,'Error','Icon','error');
                return;
            end
            ibi = app.result.ibi;
            filter = strcat(app.fileName(1:end-4),'.ibi');
            [file,path] = uiputfile(filter);
            if(~isempty(file))
                csvwrite(fullfile(path,file),ibi);
            end
        end

        % Value changed function: SearchSlider
        function SearchSliderValueChanged(app, event)
            value = app.SearchSlider.Value;
            updatePlotZooms(app, app.ZoomSpinner.Value, value);
        end

        % Value changed function: ZoomSpinner
        function ZoomSpinnerValueChanged(app, event)
            value = app.ZoomSpinner.Value;
            if(value ~= 1)
                app.SearchSlider.Enable = 'on';
            else
                % If there is no zoom, the slider is useless
                app.SearchSlider.Enable = 'off';
            end
            updatePlotZooms(app, value, app.SearchSlider.Value);              
        end

        % Value changing function: SearchSlider
        function SearchSliderValueChanging(app, event)
            changingValue = event.Value;
            updatePlotZooms(app, app.ZoomSpinner.Value, changingValue);
        end

        % Value changing function: ZoomSpinner
        function ZoomSpinnerValueChanging(app, event)
            changingValue = event.Value;
            updatePlotZooms(app, changingValue, app.SearchSlider.Value);
        end

        % Menu selected function: OpeninHRVASMenu
        function OpeninHRVASMenuSelected(app, event)
            HRVAS;
        end

        % Callback function
        function StartButtonPushed(app, event)
            if isempty(gcp)
                app.StatusLamp.Color = 'yellow';
                drawnow
                parpool;
                app.StatusLamp.Color = 'green';
            else 
                app.StatusLamp.Color = 'green';
            end
        end

        % Value changed function: ParallelPoolButton
        function ParallelPoolButtonValueChanged(app, event)
            value = app.ParallelPoolButton.Value;
            if isempty(gcp) && value == 1
                app.ProgramStateLamp = 'yellow';
                drawnow
                parpool;
                if(app.StatusLamp.Color ~= [0.00,1.00,0.00])
                    app.ProgramStateLamp = [0.65,0.65,0.65];
                end
            elseif ~isempty(gcp) && value == 0
                 app.ParallelPoolButton.Value = 1;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure;
            app.main.Name = 'main';
            app.FileMenu = uimenu(app.main);
            app.FileMenu.Text = 'File';
            app.LoadSettingsFileMenu = uimenu(app.FileMenu);
            app.LoadSettingsFileMenu.Enable = 'off';
            app.LoadSettingsFileMenu.Text = 'Load Settings File';
            app.SaveSettingsFileMenu = uimenu(app.FileMenu);
            app.SaveSettingsFileMenu.Enable = 'off';
            app.SaveSettingsFileMenu.Text = 'Save Settings File';
            app.BatchMenu = uimenu(app.FileMenu);
            app.BatchMenu.Enable = 'off';
            app.BatchMenu.Text = 'Batch';
            app.ViewMenu = uimenu(app.main);
            app.ViewMenu.Text = 'View';
            app.OpenfiguresinnewwindowMenu = uimenu(app.ViewMenu);
            app.OpenfiguresinnewwindowMenu.Enable = 'off';
            app.OpenfiguresinnewwindowMenu.Text = 'Open figures in new window';
            app.ExportMenu = uimenu(app.main);
            app.ExportMenu.Text = 'Export';
            app.IBIFileMenu = uimenu(app.ExportMenu);
            app.IBIFileMenu.MenuSelectedFcn = createCallbackFcn(app, @IBIFileMenuSelected, true);
            app.IBIFileMenu.Text = 'IBI File';
            app.MatFileMenu = uimenu(app.ExportMenu);
            app.MatFileMenu.MenuSelectedFcn = createCallbackFcn(app, @MatFileMenuSelected, true);
            app.MatFileMenu.Text = 'Mat File';
            app.OpeninHRVASMenu = uimenu(app.ExportMenu);
            app.OpeninHRVASMenu.MenuSelectedFcn = createCallbackFcn(app, @OpeninHRVASMenuSelected, true);
            app.OpeninHRVASMenu.Text = 'Open in HRVAS';
            app.CSVResultsMenu = uimenu(app.ExportMenu);
            app.CSVResultsMenu.Enable = 'off';
            app.CSVResultsMenu.Text = 'CSV Results';
            app.HelpMenu = uimenu(app.main);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.DocumentationMenu = uimenu(app.HelpMenu);
            app.DocumentationMenu.MenuSelectedFcn = createCallbackFcn(app, @DocumentationMenuSelected, true);
            app.DocumentationMenu.Text = 'Documentation';
            app.uipanel3 = uipanel(app.main);
            app.axes1 = uiaxes(app.uipanel3);
            title(app.axes1, 'ECG Signal with R points')
            xlabel(app.axes1, 'Time (s)')
            ylabel(app.axes1, 'Amplitude')
            app.axes1.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes2 = uiaxes(app.uipanel3);
            title(app.axes2, 'Tachogram')
            xlabel(app.axes2, 'Time (s)')
            ylabel(app.axes2, 'BPM')
            app.axes2.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.axes3 = uiaxes(app.uipanel3);
            title(app.axes3, 'Filtered Tachogram')
            xlabel(app.axes3, 'Time (s)')
            ylabel(app.axes3, 'BPM')
            app.SearchSliderLabel = uilabel(app.uipanel3);
            app.SearchSliderLabel.Text = 'Search';
            app.SearchSlider = uislider(app.uipanel3);
            app.SearchSlider.MajorTicks = [0 100];
            app.SearchSlider.MajorTickLabels = {'0', '100'};
            app.SearchSlider.ValueChangedFcn = createCallbackFcn(app, @SearchSliderValueChanged, true);
            app.SearchSlider.ValueChangingFcn = createCallbackFcn(app, @SearchSliderValueChanging, true);
            app.SearchSlider.Enable = 'off';
            app.ZoomSpinnerLabel = uilabel(app.uipanel3);
            app.ZoomSpinnerLabel.Text = 'Zoom:';
            app.ZoomSpinner = uispinner(app.uipanel3);
            app.ZoomSpinner.Step = 0.1;
            app.ZoomSpinner.ValueChangingFcn = createCallbackFcn(app, @ZoomSpinnerValueChanging, true);
            app.ZoomSpinner.Limits = [1 Inf];
            app.ZoomSpinner.ValueChangedFcn = createCallbackFcn(app, @ZoomSpinnerValueChanged, true);
            app.ZoomSpinner.Enable = 'off';
            app.ZoomSpinner.Value = 1;
            app.uipanel2 = uipanel(app.main);
            app.runButton = uibutton(app.uipanel2, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButton_Callback, true);
            app.runButton.Text = 'Run';
            app.results = uipanel(app.uipanel2);
            app.results.Title = 'Results';
            app.UITable = uitable(app.results);
            app.UITable.ColumnName = {'Property Name'; 'Value'; 'Unit'};
            app.UITable.RowName = {};
            app.ProgramStateLampLabel = uilabel(app.uipanel2);
            app.ProgramStateLampLabel.Text = 'Program State:';
            app.ProgramStateLamp = uilamp(app.uipanel2);
            app.TachogramGenerationPanel = uipanel(app.uipanel2);
            app.TachogramGenerationPanel.Title = 'Tachogram Generation';
            app.Switch = uiswitch(app.TachogramGenerationPanel, 'slider');
            app.Switch.Items = {'Direct', 'Smoothing Splines'};
            app.Switch.Value = 'Direct';
            app.medianFilterPostCheckBox = uicheckbox(app.TachogramGenerationPanel);
            app.medianFilterPostCheckBox.Text = 'Median Filter';
            app.CoefLabel = uilabel(app.TachogramGenerationPanel);
            app.CoefLabel.Text = 'Coef:';
            app.smoothingSplinesCoefEdit = uieditfield(app.TachogramGenerationPanel, 'numeric');
            app.smoothingSplinesCoefEdit.Value = 0.5;
            app.WindowSizeLabel = uilabel(app.TachogramGenerationPanel);
            app.WindowSizeLabel.Text = 'Window Size:';
            app.windowSizePostEdit = uieditfield(app.TachogramGenerationPanel, 'numeric');
            app.windowSizePostEdit.Value = 3;
            app.splinesRSquareText = uilabel(app.TachogramGenerationPanel);
            app.splinesRSquareText.Text = 'X.XXX';
            app.text24 = uilabel(app.TachogramGenerationPanel);
            app.text24.Text = 'R²';
            app.BeatProcessingPanel = uipanel(app.uipanel2);
            app.BeatProcessingPanel.Title = 'Beat Processing';
            app.ensembleCheckBox = uicheckbox(app.BeatProcessingPanel);
            app.ensembleCheckBox.Text = 'Ensemble Filter';
            app.mediaCheckBox = uicheckbox(app.BeatProcessingPanel);
            app.mediaCheckBox.Text = 'MAD Filter';
            app.missedBeatsCheckBox = uicheckbox(app.BeatProcessingPanel);
            app.missedBeatsCheckBox.Text = 'Missed Beats Detection';
            app.MinimumCorrelationLabel = uilabel(app.BeatProcessingPanel);
            app.MinimumCorrelationLabel.Text = 'Minimum Correlation:';
            app.minimumCorrelationEdit = uieditfield(app.BeatProcessingPanel, 'numeric');
            app.minimumCorrelationEdit.Value = 0.4;
            app.windowSizeEdit = uieditfield(app.BeatProcessingPanel, 'numeric');
            app.windowSizeEdit.Value = 15;
            app.TauLabel = uilabel(app.BeatProcessingPanel);
            app.TauLabel.Text = 'Tau:';
            app.missedBeatsTolerancePercentEdit = uieditfield(app.BeatProcessingPanel, 'numeric');
            app.missedBeatsTolerancePercentEdit.Value = 20;
            app.ToleranceLabel = uilabel(app.BeatProcessingPanel);
            app.ToleranceLabel.Text = 'Tolerance (%):';
            app.numMissedBeatsText = uilabel(app.BeatProcessingPanel);
            app.numMissedBeatsText.Text = '0';
            app.numRemovedBeatsMadFilterText = uilabel(app.BeatProcessingPanel);
            app.numRemovedBeatsMadFilterText.Text = '0';
            app.numRemovedBeatsEnsembleText = uilabel(app.BeatProcessingPanel);
            app.numRemovedBeatsEnsembleText.Text = '0';
            app.text16 = uilabel(app.BeatProcessingPanel);
            app.text16.Text = {'Beats'; 'removed'};
            app.FileSelectionPanel = uipanel(app.uipanel2);
            app.FileSelectionPanel.Title = 'File Selection';
            app.fileSelectButton = uibutton(app.FileSelectionPanel, 'push');
            app.fileSelectButton.ButtonPushedFcn = createCallbackFcn(app, @fileSelectButton_Callback, true);
            app.fileSelectButton.Text = 'Select';
            app.text34 = uilabel(app.FileSelectionPanel);
            app.text34.Text = 'Current File:';
            app.filename = uilabel(app.FileSelectionPanel);
            app.filename.Text = 'None';
            app.ParallelPoolButton = uibutton(app.uipanel2, 'state');
            app.ParallelPoolButton.ValueChangedFcn = createCallbackFcn(app, @ParallelPoolButtonValueChanged, true);
            app.ParallelPoolButton.Text = 'Parallel Pool';
        end
    end

    methods (Access = public)

        % Construct app
        function app = main_App

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @main_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end